classdef FanaticActo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FanaticActoUIFigure        matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        LoadFileMenu               matlab.ui.container.Menu
        SelectFileMenu             matlab.ui.container.Menu
        Menu                       matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        SaveasMenu                 matlab.ui.container.Menu
        CircadianMenu              matlab.ui.container.Menu
        LocalGMTMenu               matlab.ui.container.Menu
        GraphicMenu                matlab.ui.container.Menu
        ImportMaskFileMenu         matlab.ui.container.Menu
        AdjustMaskalphaMenu        matlab.ui.container.Menu
        LDScalePositionMenu        matlab.ui.container.Menu
        TopMenu                    matlab.ui.container.Menu
        BottomMenu                 matlab.ui.container.Menu
        ColorMenu                  matlab.ui.container.Menu
        LoadFileButton             matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        CircadianButton            matlab.ui.control.Button
        FanaticActoLabel           matlab.ui.control.Label
        LightHourLabel             matlab.ui.control.Label
        LightHourEditField         matlab.ui.control.NumericEditField
        HoursDayEditFieldLabel     matlab.ui.control.Label
        HoursDayEditField          matlab.ui.control.NumericEditField
        EndDayLabel                matlab.ui.control.Label
        EndDayDropDown             matlab.ui.control.DropDown
        StartDayLabel              matlab.ui.control.Label
        StartDayDropDown           matlab.ui.control.DropDown
        DoublePlotCheckBox         matlab.ui.control.CheckBox
        LDMaskCheckBox             matlab.ui.control.CheckBox
        CageLabel                  matlab.ui.control.Label
        CageDropDown               matlab.ui.control.DropDown
        GraphButton                matlab.ui.control.Button
        PleaseLoadFileAgainLabel   matlab.ui.control.Label
        SpaceDayLabel              matlab.ui.control.Label
        SpaceDayEditField          matlab.ui.control.NumericEditField
        pixLabel                   matlab.ui.control.Label
        BinsHourDropDownLabel      matlab.ui.control.Label
        BinsHourDropDown           matlab.ui.control.DropDown
        BinHeightEditFieldLabel    matlab.ui.control.Label
        BinHeightEditField         matlab.ui.control.NumericEditField
        BinWidthEditFieldLabel     matlab.ui.control.Label
        BinWidthEditField          matlab.ui.control.NumericEditField
        ColorSampleEditFieldLabel  matlab.ui.control.Label
        ColorSampleEditField       matlab.ui.control.NumericEditField
        ColorButton                matlab.ui.control.Button
        GradientEditFieldLabel     matlab.ui.control.Label
        GradientEditField          matlab.ui.control.NumericEditField
        UIAxes2                    matlab.ui.control.UIAxes
        pixLabel_2                 matlab.ui.control.Label
        pixLabel_3                 matlab.ui.control.Label
        PredictionButton           matlab.ui.control.Button
        ManualMaskCheckBox         matlab.ui.control.CheckBox
        LDScaleCheckBox            matlab.ui.control.CheckBox
        HourXTickLabel             matlab.ui.control.Label
        HourPerTickEditField       matlab.ui.control.NumericEditField
        OnsetMarkCheckBox          matlab.ui.control.CheckBox
        DayDropDownLabel           matlab.ui.control.Label
        DayDropDown                matlab.ui.control.DropDown
        StarNODropDownLabel        matlab.ui.control.Label
        StarNODropDown             matlab.ui.control.DropDown
        ListButton                 matlab.ui.control.Button
        RegressionCheckBox         matlab.ui.control.CheckBox
        PredDayEditFieldLabel      matlab.ui.control.Label
        PredDayEditField           matlab.ui.control.NumericEditField
        MultiFitUITable            matlab.ui.control.Table
        RegSpinner                 matlab.ui.control.Spinner
        ChiPCheckBox               matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
        DayDropDown_2Label         matlab.ui.control.Label
        DayDropDown_2              matlab.ui.control.DropDown
        DayDropDown_3Label         matlab.ui.control.Label
        DayDropDown_3              matlab.ui.control.DropDown
        DisplayRangeLabel          matlab.ui.control.Label
        ShowRangeLabel             matlab.ui.control.Label
        ProgressBarEditField       matlab.ui.control.EditField
        HighlightCheckBox          matlab.ui.control.CheckBox
    end


    properties (Access = private)
        
        
        apppath = [];
        libpath = [];
        datapath = [];
        
        ActoData = [];
        ActoRef = [];
        ActoFigureUI = [];
        % Important Parameters
        StartDay = [];
        EndDay = [];
        
        % Figure Handle
        starHandle = [];
        mainHandle = [];
        regHandle = [];
        predHandle = [];
        %maskHandle = [];
        
        % Figure Cropping
        %indRowLeft = 1;
        %indRowRight = 0;
        %indColumnLeft = 1;
        %indColumnRight = 0;
        
        % app layer parameters
        % 0: hide all; 1: Circadian; 2: Graph; 3: Color;4: prediction
        OptionVisible = 0;
        CageValue = 0;
        FileLoaded = 0;
        
        fm = '';
        fmask = '';
        Gray07 = [0.7 0.7 0.7];
        TiffanyBlue = [0.5059 0.8471 0.8157];
        
        regLineNo = [];
        regStartDay = [];
        regEndDay = [];
       
        % tmp
        mynewfigure = [];
        AutoCageMenu = [];
    end

    methods (Access = private)
        
        % selector
        function app = ButtonSwitchOff(app)
            if app.OptionVisible == 1
                CircadianSwitch(app, app.Gray07, 'off');
            elseif app.OptionVisible == 2
                GraphSwitch(app, app.Gray07, 'off');
            elseif app.OptionVisible == 3
                ColorSwitch(app, app.Gray07, 'off', [449 149 0 0]);
            elseif app.OptionVisible == 4
                PredictionSwitch(app, app.Gray07, 'off');
            else
                CircadianSwitch(app, app.Gray07, 'off');
                GraphSwitch(app, app.Gray07, 'off');
                ColorSwitch(app, app.Gray07, 'off', [449 149 0 0]);
                PredictionSwitch(app, app.Gray07, 'off');
            end
                
        end
        function app = ButtonSwitchOn(app)
            if app.OptionVisible == 1
                CircadianSwitch(app, app.TiffanyBlue, 'on');
            elseif app.OptionVisible == 2
                GraphSwitch(app, app.TiffanyBlue, 'on');
            elseif app.OptionVisible == 3
                ColorSwitch(app, app.TiffanyBlue, 'on', [449 149 137 186]);
            elseif app.OptionVisible == 4
                PredictionSwitch(app, app.TiffanyBlue, 'on');
            end     
        end

        function app = CircadianSwitch(app, tb, sw)
            app.CircadianButton.BackgroundColor = tb;
            app.LightHourLabel.Visible = sw;
            app.LightHourEditField.Visible = sw;
            app.HoursDayEditFieldLabel.Visible = sw;
            app.HoursDayEditField.Visible = sw;
            app.StartDayLabel.Visible = sw;
            app.StartDayDropDown.Visible = sw;
            app.EndDayLabel.Visible = sw;
            app.EndDayDropDown.Visible = sw;
        end
        
        function app = GraphSwitch(app, tb, sw)
            app.GraphButton.BackgroundColor = tb;
            app.BinsHourDropDownLabel.Visible = sw;
            app.BinsHourDropDown.Visible = sw;
            app.BinHeightEditFieldLabel.Visible = sw;
            app.BinHeightEditField.Visible = sw;
            app.BinWidthEditFieldLabel.Visible = sw;
            app.BinWidthEditField.Visible = sw;
            app.SpaceDayEditField.Visible = sw;
            app.SpaceDayLabel.Visible = sw;
            app.HourXTickLabel.Visible = sw;
            app.HourPerTickEditField.Visible = sw;
            app.HighlightCheckBox.Visible = sw;
            
            app.pixLabel.Visible = sw;
            app.pixLabel_2.Visible = sw;
            app.pixLabel_3.Visible = sw;
            
            app.PleaseLoadFileAgainLabel.Visible = 'off';

            app.DoublePlotCheckBox.Visible = sw;
            app.LDMaskCheckBox.Visible = sw;
            app.ManualMaskCheckBox.Visible = sw;
            app.LDScaleCheckBox.Visible = sw;
            
        end
    
        function app = ColorSwitch(app, tb, sw, pos)
            app.ColorButton.BackgroundColor = tb;
            app.ColorSampleEditFieldLabel.Visible = sw;
            app.ColorSampleEditField.Visible = sw;
            app.GradientEditFieldLabel.Visible = sw;
            app.GradientEditField.Visible = sw;
            
            app.UIAxes2.Visible = sw;
            app.UIAxes2.Position = pos;
            %app.UIAxes2.Position = [449 149 0 0];
            %app.UIAxes2.Position = [449 149 137 186];
        end
    
        function app = PredictionSwitch(app, tb, sw)
            app.PredictionButton.BackgroundColor = tb;
            app.OnsetMarkCheckBox.Visible = sw;
            app.DayDropDownLabel.Visible = sw;
            app.DayDropDown.Visible = sw;
            app.StarNODropDownLabel.Visible = sw;
            app.StarNODropDown.Visible = sw;
            app.RegressionCheckBox.Visible = sw;
            app.PredDayEditField.Visible = sw;
            app.PredDayEditFieldLabel.Visible = sw;
            app.RegSpinner.Visible = sw;
            app.ChiPCheckBox.Visible = sw;
            
            app.MultiFitUITable.Visible = sw;
            
        end

        function app = CreatSelectFileMenu(app)
            cc = 1;
            for i = 1:length(app.CageDropDown.ItemsData)-1
                app.AutoCageMenu{cc} = uimenu(app.SelectFileMenu);
                %app.AutoCageMenu.MenuSelectedFcn = createCallbackFcn(app, @CageMenuSelected, true);
                app.AutoCageMenu{cc}.Accelerator = '1';
                app.AutoCageMenu{cc}.Text = ['Cage ' num2str(app.CageDropDown.ItemsData(cc))];
                cc = cc + 1;
            end
            app.AutoCageMenu{cc} = uimenu(app.SelectFileMenu);
            app.AutoCageMenu{cc}.MenuSelectedFcn = createCallbackFcn(app, @CageMenuSelected2, true);
            app.AutoCageMenu{cc}.Accelerator = '1';
            app.AutoCageMenu{cc}.Text = 'Other File';
            
            
        end
        function app = cageMenuCheck(app, n)
            app.Cage2Menu.Checked = 'off';
            app.Cage3Menu.Checked = 'off';
            app.Cage5Menu.Checked = 'off';
            app.Cage7Menu.Checked = 'off';
            app.OtherFileMenu.Checked = 'off';
            if n == 2
                app.Cage2Menu.Checked = 'on';
            elseif n == 3
                app.Cage3Menu.Checked = 'on';
            elseif n == 5
                app.Cage5Menu.Checked = 'on';
            elseif n == 7
                app.Cage7Menu.Checked = 'on';
            elseif n == -1
                app.OtherFileMenu.Checked = 'on';
                
            end
        end
    
        function drawActo(app)
            %{
            if 1
                figure(7788);
            
                if app.DoublePlotCheckBox.Value
                    app.mainHandle = imagesc(app.ActoData.actopic(app.ActoData.indRowTop:app.ActoData.indRowBottom, 1:app.ActoData.indColumnRightDouble, :));
                else
                    app.mainHandle = imagesc(app.ActoData.actopic(app.ActoData.indRowTop:app:app.ActoData.indRowBottom, 1:app.ActoData.indColumnRight, :));
                end
                %fig = uifigure;
                %ax = uiaxes(fig);
                %app.mainHandle = imagesc(app.ActoData.actopic(app.indRowLeft:app.indRowRight, app.indColumnLeft:app.indColumnRight, :), 'parent', ax)
                
                
                xticks(app.ActoData.xt)
                xticklabels(app.ActoData.xtl)
                yticks(app.ActoData.yt)
                yticklabels(app.ActoData.ytl)
                %a = gca;
                
                %set(a,'box','off','color','none')
                %b = axes('Position',get(gca,'Position'),'box','on','xtick',[],'ytick',[], 'LineWidth', 1);
                %axes(a)
                %a.LineWidth = 1;
                %linkaxes([a b])
                set(gca,'TickDir','out'); % The only other option is 'in'
                
                %set(gca,'box','off')
                truesize
                
                figure(7789);
                histogram(app.ActoData.actoHist(end-1, :));


            %}
                figure(7788);
                if app.DoublePlotCheckBox.Value
                    app.mainHandle = imagesc(app.ActoData.actopic(app.ActoData.indRowTop:app.ActoData.indRowBottom, 1:app.ActoData.indColumnRightDouble, :));
                else
                    app.mainHandle = imagesc(app.ActoData.actopic(app.ActoData.indRowTop:app.ActoData.indRowBottom, 1:app.ActoData.indColumnRight, :));
                end
                xticklabels(app.ActoData.xtl)
                yticks(app.ActoData.yt)
                yticklabels(app.ActoData.ytl)
                set(gca,'TickDir','out'); % The only other option is 'in'
                
                if app.HighlightCheckBox.Value
                    set(gcf, 'WindowButtonMotionFcn', @mouseMove);
                else
                    set (gcf, 'WindowButtonMotionFcn', []);
                end
                function mouseMove (object, eventdata)
        
                    C = get (gca, 'CurrentPoint');
                    %h = plot([0 1], [0 1]);
                    %D = get(gca)
                    if app.DoublePlotCheckBox.Value
                        app.mainHandle = imagesc(app.ActoData.actopic(app.ActoData.indRowTop:app.ActoData.indRowBottom, 1:app.ActoData.indColumnRightDouble, :));
                
                        line1 = linspace(0, app.ActoData.indColumnRightDouble, 10);
                    else
                        app.mainHandle = imagesc(app.ActoData.actopic(app.ActoData.indRowTop:app:app.ActoData.indRowBottom, 1:app.ActoData.indColumnRight, :));
                
                        line1 = linspace(0, app.ActoData.indColumnRight, 10);
                    end
                    xticks(app.ActoData.xt)
                    xticklabels(app.ActoData.xtl)
                    yticks(app.ActoData.yt)
                    yticklabels(app.ActoData.ytl)
                    set(gca,'TickDir','out'); % The only other option is 'in'
                
                    %title(gca, ['(X,Y) = (', num2str(C(1,1)), ', ',num2str(C(1,2)), ')']);
                    a = floor(C(1,2)/app.ActoData.BHPixSp);
                    b = a + 1;
                    aa = a * app.ActoData.BHPixSp + 1;
                    bb = b * app.ActoData.BHPixSp;
                    if C(1,1) > 0
                        hold on
                        %line1 = linspace(0, app.ActoData.indColumnRightDouble., 10)
                        line2 = linspace(aa, aa, 10);
                        line3 = linspace(bb, bb, 10);
                        %line11 = [line1, fliplr(line1), 'b'];
                        %line22 = [line2, fliplr(line3), 'b'];
                        
                        plot(line1, line2, 'b')
                        plot(line1, line3, 'b')
                        hold off
                        
                    end
                    %h = plot([0 1], [0 1]);
                    %hold on
                    %plot([0 1], [C(1,2) C(1,2)])
                    %hold off


                end
            
            
        end
    
        function app = InceaseProgressBar(app, i)
            if strcmp(app.ProgressBarEditField.Visible, 'off')
                app.ProgressBarEditField.Visible = 'on';
            end
            if i < 5
                app.ProgressBarEditField.Value = repmat('▉', 1, 4 * i);
                
            elseif i == 5
                
                app.ProgressBarEditField.Visible = 'off';
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % resize
            %app.FanaticActoUIFigure.Position = [100 100 640 480];
            % positioning
            %app.FanaticActoLabel.Position = [200,375,240,50];
            %app.UIAxes.Position = [200,160,240,160];
            
            % buttons
            app.LoadFileButton.Position = [50 50 100 40];
            app.CircadianButton.Position = [315 55 96 31];
            app.GraphButton.Position = [410 55 96 31];
            app.ColorButton.Position = [506 55 96 31];
            app.PredictionButton.Position = [315 25 96 31];
            
            % Graph options
            app.BinsHourDropDownLabel.Position = [45 300 90 22];
            app.BinsHourDropDown.Position = [140 300 50 22];
            
            % Color options
            app.ColorSampleEditFieldLabel.Position = [27 300 100 22];
            app.ColorSampleEditField.Position = [140 300 50 22];
            app.GradientEditFieldLabel.Position = [27 260 90 20];
            app.GradientEditField.Position = [140 260 50 22];
            
            app.DayDropDown.Position = [140 300 50 22];
            app.DayDropDownLabel.Position = [58 300 67 22];
            app.StarNODropDown.Position = [140 260 50 22];
            app.StarNODropDownLabel.Position = [58 260 67 22];
            app.PredDayEditField.Position = [140 220 50 22];
            app.PredDayEditFieldLabel.Position = [27 220 80 22];
            
            % 
            app.OnsetMarkCheckBox.Position = [460 300 140 22];
            app.RegressionCheckBox.Position = [460 260 140 22];
            app.RegSpinner.Position = [565 260 50 22];
            app.ChiPCheckBox.Position = [460 220 140 22];
            %%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            % Hide all buttons
            app = ButtonSwitchOff(app);
            
            % data retrieve methods
            if ismac
                app.apppath = pwd;
                if strcmp(app.apppath(end-3:end), '/lib')
                    cd ..
                end
                app.apppath = pwd;
                app.libpath = [pwd '/lib'];
                app.datapath = [pwd 'Data/data/'];
                
                app.ActoData.apppath = pwd;
                app.ActoData.libpath = [pwd '/lib'];
                app.ActoData.datapath = [pwd 'Data/data/'];
                
                path(path, app.libpath);
                flist = ls(app.datapath);
                numbers = sort(sscanf(flist, '%d\t'));
                
            elseif isunix
                disp('Platform not supported')
            elseif ispc
                % Code to run on Windows platform
                app.apppath = pwd;
                if strcmp(app.apppath(end-3:end), '\lib')
                    cd ..
                end
                app.apppath = pwd;
                app.libpath = [pwd '\lib'];
                app.datapath = [pwd 'Data\data\'];
                app.ActoData.apppath = pwd;
                app.ActoData.libpath = [pwd '\lib'];
                app.ActoData.datapath = [pwd 'Data\data\'];
                
                path(path , app.libpath)
                flist = ls(app.datapath);
                flist = str2double(string(flist));
                numbers = sort(flist(~isnan(flist)));
                
            else
                disp('Platform not supported')
            end
            
            app.CageValue = app.CageDropDown.Value;
            app.FileLoaded = 0;
            
            corn_gray = imread('82.jpg');
            imshow(corn_gray, 'parent', app.UIAxes);
            
            
            app.CageDropDown.Items = cellstr([string(numbers); 'Other File']);
            app.CageDropDown.Value = num2str(numbers(1));
            app.CageDropDown.ItemsData = [numbers; -1];
            app = app.CreatSelectFileMenu();
            
            numbers = [1;2;4;6;12;30;60;120];
            app.BinsHourDropDown.Items = cellstr(string(numbers));
            app.BinsHourDropDown.ItemsData = numbers;
            app.BinsHourDropDown.Value = app.BinsHourDropDown.ItemsData(5);
            

            %app.MultiFitUITable = uitable(app.FanaticActoUIFigure,'Data',tdata,'ColumnEditable',true);
            %app.MultiFitUITable.ColumnName = {'# of Line'; 'Initiate Day'; 'Terminated Day'};
            %app.MultiFitUITable.RowName = {};
            app.MultiFitUITable.ColumnEditable = [false true true];
            
            % actoData default values
            % First Level Parameters
            app.ActoData.LOCALGMT = 8;
            app.ActoData.TIMESHIFT = app.LightHourEditField.Value;
            app.ActoData.HRPERDAY = app.HoursDayEditField.Value;
            
            app.ActoData.BINPERHR = app.BinsHourDropDown.Value;
            app.ActoData.MINPERBIN = 60/app.ActoData.BINPERHR;
            app.ActoData.BINHALFHR = app.ActoData.BINPERHR/2;
            app.ActoData.BINPERDAY = app.ActoData.BINPERHR * app.ActoData.HRPERDAY;
            
            app.ActoData.BHPix = 50;
            app.ActoData.BHSpace = app.SpaceDayEditField.Value;
            app.ActoData.BHPixSp = app.ActoData.BHPix + app.ActoData.BHSpace;
            
            app.ActoData.BWPix = 5;
            app.ActoData.BWSpace = 1;
            app.ActoData.BWPixSp = app.ActoData.BWPix + app.ActoData.BWSpace;
            % Default Separation
            if rem(app.ActoData.BINHALFHR, 1) == 0
                app.ActoData.BinSep = app.ActoData.BINHALFHR; % bins
            else
                app.ActoData.BinSep = app.ActoData.BINPERHR;
            end
            
            app.ActoData.LDMask = app.LDMaskCheckBox.Value;
            % Color
            app.ActoData.ColorSample = app.ColorSampleEditField.Value;
        end

        % Callback function: LoadFileButton, LoadFileMenu
        function LoadFileButtonPushed(app, event)
            
            hourPerTickValue = app.HourPerTickEditField.Value;
            
            %%%% Load csv file
            currentCageValue = app.CageDropDown.Value;
            if currentCageValue == -1 %%%%% error warning %%%%%
                [file, path] = uigetfile('*.csv', '*.txt');
                if file ~= 0
                    
                    app.ActoData = readActoCSV(app.ActoData, value, [path file]);
                else
                    return
                end
            else
                
                %app.ActoData.fmask = ['./data/' num2str(value) '/mask/runner' num2str(value) 'mask.csv']
                if ~app.FileLoaded
         
                    app.ActoData = readActoCSV(app.ActoData, currentCageValue, app.datapath);
                    
                    app.FileLoaded = 1;
                elseif app.CageValue ~= currentCageValue
                    app.ActoData = readActoCSV(app.ActoData, currentCageValue, app.datapath);
                    app.CageValue = currentCageValue;
                    
                end
            end % of Load csv file
                    
            InceaseProgressBar(app, 1);
            app.ActoData = data2Hist(app.ActoData);
            InceaseProgressBar(app, 2);
            app.ActoData = hist2Graph(app.ActoData);
            InceaseProgressBar(app, 3);
            app.ActoData = fastTick(app.ActoData, hourPerTickValue);
            %[app.indRowRight, app.indColumnRight, ~] = size(app.ActoData.actopic);
            
            if ~isempty(app.ActoFigureUI)
                delete(app.ActoFigureUI)
            end
            %app.ActoFigureUI = ActoFigureApp(app.ActoData.actopic, 1, 1);
            drawActo(app);
        
        
            % main app appearance
            app.CircadianButton.Visible = 'on';
            app.GraphButton.Visible = 'on';
            app.ColorButton.Visible = 'on';
            app.PleaseLoadFileAgainLabel.Visible = 'off';
            
            app.StartDayDropDown.Items = cellstr(string(1:app.ActoData.lenDay));
            app.StartDayDropDown.ItemsData = 1:app.ActoData.lenDay;
            app.EndDayDropDown.Items = cellstr(string(1:app.ActoData.lenDay));
            app.EndDayDropDown.ItemsData = 1:app.ActoData.lenDay;
            app.EndDayDropDown.Value = app.ActoData.lenDay;
            app.StartDay = app.StartDayDropDown.Value;
            app.EndDay = app.EndDayDropDown.Value;
            
            
            app.DayDropDown.Items = cellstr(string(1:(app.EndDay - app.StartDay + 1)));
            app.DayDropDown.ItemsData = 1:(app.EndDay - app.StartDay + 1);
            app.StarNODropDown.Items = {'1', '2', '3', '4', '5', 'NaN'};
            app.StarNODropDown.ItemsData = [1 2 3 4 5 NaN];
            InceaseProgressBar(app, 4);
            value = app.RegSpinner.Value;
            app.regLineNo = (1:value)';
            app.regStartDay = ones(value, 1);
            app.regEndDay = ones(value, 1) * app.EndDay;
            app.MultiFitUITable.Data = table(app.regLineNo, app.regStartDay, app.regEndDay, 'VariableNames',{'Lines', 'InitialDay', 'TerminatedDay'});
            %app.MultiFitUITable
            tmp = app.ActoData.actoHist;
            save('hdata.mat', 'tmp');
            InceaseProgressBar(app, 5);
        end

        % Callback function
        function DrawButtonPushed(app, event)
            
        end

        % Callback function
        function DrawButtonPushed2(app, event)

        end

        % Button pushed function: CircadianButton
        function CircadianButtonPushed(app, event)
            % ideal structure
            if app.OptionVisible ~= 0
                ButtonSwitchOff(app);
            end
            if app.OptionVisible ~= 1
                app.OptionVisible = 1;
                ButtonSwitchOn(app);  
            else
                app.OptionVisible = 0;
            end
        end

        % Value changed function: LightHourEditField
        function LightHourEditFieldValueChanged(app, event)
            app.ActoData.TIMESHIFT = app.LightHourEditField.Value;
            if app.FileLoaded
                c = clock;
                hourPerTickValue = app.HourPerTickEditField.Value;
                app.ActoData = data2Hist(app.ActoData);
                app.ActoData = hist2Graph(app.ActoData);
                app.ActoData = fastTick(app.ActoData, hourPerTickValue);
                
                drawActo(app);
                c = clock - c;
                cc = c(5)*60 + c(6);
                app.PleaseLoadFileAgainLabel.Text = ['It takes ' num2str(round(cc, 2)) ' seconds'];
                app.PleaseLoadFileAgainLabel.Visible = 'on';
           
            end
        end

        % Value changed function: StartDayDropDown
        function StartDayDropDownValueChanged(app, event)
            if app.FileLoaded
                value = app.StartDayDropDown.Value;
            
                app.StartDay = value;
            
                app.EndDayDropDown.Items = cellstr(string(value:app.ActoData.lenDay));
                app.EndDayDropDown.ItemsData = value:app.ActoData.lenDay;
                app.EndDayDropDown.Value = app.EndDayDropDown.ItemsData(end);
                
                app.ActoData.indRowTop = app.ActoData.BHPixSp*(value-1) + 1;
                
                %app.regStartDay 
                drawActo(app);
            end
            
        end

        % Value changed function: EndDayDropDown
        function EndDayDropDownValueChanged(app, event)
            if app.FileLoaded
                value = app.EndDayDropDown.Value;
                app.EndDay = value;
                app.StartDayDropDown.Items = cellstr(string(1:value));
                app.StartDayDropDown.ItemsData = 1:value;
                
                app.ActoData.indRowBottom = app.ActoData.BHPixSp * value - app.ActoData.BHSpace;
                drawActo(app)
            end
        end

        % Value changed function: DoublePlotCheckBox
        function DoublePlotCheckBoxValueChanged(app, event)
            if app.FileLoaded%value = app.DoublePlotCheckBox.Value;
                drawActo(app)
            end
        end

        % Value changed function: LDMaskCheckBox
        function LDMaskCheckBoxValueChanged(app, event)
            if app.FileLoaded
                app.ManualMaskCheckBox.Value = 0;
                app.ActoData.LDMask = app.LDMaskCheckBox.Value;
                if app.ActoData.LDMask
                    app.ActoData.actopic = app.ActoData.actopicmaskDouble;
                    drawActo(app);
                   
                    
                else
                    app.ActoData.actopic = app.ActoData.actopicDouble;
                    drawActo(app);
                    
                end
            end
        end

        % Value changed function: CageDropDown
        function CageDropDownValueChanged(app, event)
            value = app.CageDropDown.Value;
            % value change & Load button push
            % value change & button NOT push
            
        end

        % Callback function
        function BinsHourEditFieldValueChanged(app, event)
            bhvalue = app.BinsHourEditField.Value;
            app.PleaseLoadFileAgainLabel.Visible = 'on';


        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            if app.OptionVisible ~= 0
                ButtonSwitchOff(app);
            end
            if app.OptionVisible ~= 2
                app.OptionVisible = 2;
                ButtonSwitchOn(app);  
            else
                app.OptionVisible = 0;
            end
        end

        % Value changed function: SpaceDayEditField
        function SpaceDayEditFieldValueChanged(app, event)
            value = app.SpaceDayEditField.Value;
            app.PleaseLoadFileAgainLabel.Visible = 'on';
            
        end

        % Value changed function: BinsHourDropDown
        function BinsHourDropDownValueChanged(app, event)
            app.ActoData.BINPERHR = app.BinsHourDropDown.Value;
            app.ActoData.MINPERBIN = 60/app.ActoData.BINPERHR;
            app.ActoData.BINHALFHR = app.ActoData.BINPERHR/2;
            app.ActoData.BINPERDAY = app.ActoData.BINPERHR * app.ActoData.HRPERDAY;
            app.PleaseLoadFileAgainLabel.Visible = 'on';
            
        end

        % Value changed function: ColorSampleEditField
        function ColorSampleEditFieldValueChanged(app, event)
            app.ActoData.ColorSample = app.ColorSampleEditField.Value;
            app.ActoData.tmpbin = colorGradient(app.ActoData);
            imshow(app.ActoData.tmpbin, 'parent', app.UIAxes2)
            
            
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            if app.OptionVisible ~= 0
                ButtonSwitchOff(app);
            end
            if app.OptionVisible ~= 3
                app.OptionVisible = 3;
                ButtonSwitchOn(app);  
            else
                app.OptionVisible = 0;
            end
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Callback function
        function OtherFileMenuSelected(app, event)
            
        end

        % Callback function
        function Cage2MenuSelected2(app, event)
            app.CageDropDown.Value = 2;
            app = app.cageMenuCheck(app.CageDropDown.Value);
        end

        % Callback function
        function Cage3MenuSelected2(app, event)
            app.CageDropDown.Value = 3;
            app = app.cageMenuCheck(app.CageDropDown.Value);
        end

        % Callback function
        function Cage5MenuSelected2(app, event)
            app.CageDropDown.Value = 5;
            app = app.cageMenuCheck(app.CageDropDown.Value);
        end

        % Callback function
        function Cage7MenuSelected2(app, event)
            app.CageDropDown.Value = 7;
            app = app.cageMenuCheck(app.CageDropDown.Value);
        end

        % Callback function
        function OtherFileMenuSelected2(app, event)
            app.CageDropDown.Value = -1;
            app = app.cageMenuCheck(app.CageDropDown.Value);
        end

        % Value changed function: ManualMaskCheckBox
        function ManualMaskCheckBoxValueChanged(app, event)
            app.LDMaskCheckBox.Value = 0;
            value = app.ManualMaskCheckBox.Value;
            
            if value
                if app.ActoData.existMask
                    if isempty(app.ActoData.MaskCSVs)
            
                        app.ActoData.actopic = app.ActoData.actopicCustomMaskDouble;
                        drawActo(app);
               
                
                    else
                        app.ActoData.actopic = app.ActoData.actopicDouble;
                        drawActo(app);
                
                    end
                else
                    app.PleaseLoadFileAgainLabel.Text = ['Can not show custom mask.'];
                    app.PleaseLoadFileAgainLabel.Visible = 'on';
                    
                    
                end
            else
                app.PleaseLoadFileAgainLabel.Text = '';
            end
     
        end

        % Value changed function: HourPerTickEditField
        function HourPerTickEditFieldValueChanged(app, event)
            value = app.HourPerTickEditField.Value;
            app.ActoData = fastTick(app.ActoData, value);
            xticks(app.ActoData.xt)
            xticklabels(app.ActoData.xtl)
            
        end

        % Value changed function: OnsetMarkCheckBox
        function OnsetMarkCheckBoxValueChanged(app, event)
            value = app.OnsetMarkCheckBox.Value;
            if value
                app.ActoData = hist2pix(app.ActoData, app.ActoData.onsetGuess, 0);
                
                figure(7788)
                hold on
                app.starHandle = plot(app.ActoData.pixhisto(app.StartDay:app.EndDay), app.ActoData.yt(1:app.EndDay - app.StartDay + 1), 'r*');
                hold off
                app.starHandle
            else
                delete(app.starHandle);
            end
        end

        % Value changed function: StarNODropDown
        function StarNODropDownValueChanged(app, event)
            Value = app.DayDropDown.Value;
            starValue = app.StarNODropDown.Value;
            dayValue = app.StartDay + Value - 1;
            % change values
            app.ActoData.onsetGuessNo(dayValue) = starValue;
            if isnan(starValue)
                app.ActoData.onsetGuess(dayValue) = starValue;
            else
                app.ActoData.onsetGuess(dayValue) = app.ActoData.onset(starValue, dayValue);
            end
            app.ActoData = hist2pix(app.ActoData, app.ActoData.onsetGuess, 0);
            size(app.ActoData.pixhisto)
            app.starHandle.XData = app.ActoData.pixhisto(app.StartDay:app.EndDay);


        end

        % Value changed function: DayDropDown
        function DayDropDownValueChanged(app, event)
            % start with StartDayDropDown
            value = app.DayDropDown.Value;
            app.StarNODropDown.Value = app.ActoData.onsetGuessNo(app.StartDay + value - 1);
            
            
        end

        % Button pushed function: PredictionButton
        function PredictionButtonPushed(app, event)
            if app.OptionVisible ~= 0
                ButtonSwitchOff(app);
            end
            if app.OptionVisible ~= 4
                app.OptionVisible = 4;
                ButtonSwitchOn(app);
            else
                app.OptionVisible = 0;
            end
        end

        % Value changed function: RegressionCheckBox
        function RegressionCheckBoxValueChanged(app, event)
            value = app.RegressionCheckBox.Value;
            valueLine = app.RegSpinner.Value;
            if value
                
                app.ActoData = actoRegression(app.ActoData, app.regStartDay, app.regEndDay,  app.StartDay);
                
                figure(7788)
                hold on
                app.regHandle = plot(app.ActoData.pred_x, app.ActoData.y, 'lineWidth', 2);
                hold off
            else
                delete(app.regHandle);
            end
                
            
        end

        % Value changed function: PredDayEditField
        function PredDayEditFieldValueChanged(app, event)
            value = app.PredDayEditField.Value;


            tmpyt = app.ActoData.BHPix / 2 + (value - 1) * app.ActoData.BHPix + (value - 1) * app.ActoData.BHSpace;
            tmpymax = value * app.ActoData.BHPix + (value - 1) * app.ActoData.BHSpace;
            pred_x = tmpyt * app.ActoData.M + app.ActoData.B;
            figure(7788)
            hold on
            if tmpymax >= app.ActoData.yt(app.EndDayDropDown.Value)
                ylim([0 tmpymax])
            elseif value < 1
                tmpymin = (value - 1) * app.ActoData.BHPix - (value - 1) * app.ActoData.BHSpace;
                tmpymax = app.EndDayDropDown.Value * app.ActoData.BHPix + (app.EndDayDropDown.Value - 1) * app.ActoData.BHSpace;
                ylim([tmpymin tmpymax])
                    
            end
            
            app.predHandle = plot(pred_x, tmpyt, '*');
            fprintf("pred time: %f, %f\n", round(pred_x, 4), round((pred_x - floor(pred_x/31))/60, 4));
            hold off
        end

        % Value changed function: RegSpinner
        function RegSpinnerValueChanged(app, event)

            value = app.RegSpinner.Value;
            if value > length(app.regLineNo)
                T2 = cell2table({value app.StartDayDropDown.Value app.EndDayDropDown.Value}, 'VariableNames',{'Lines','InitialDay', 'TerminatedDay'});

                app.MultiFitUITable.Data = [app.MultiFitUITable.Data; T2];
                app.regLineNo = (1:value)';
                app.regStartDay = table2array(app.MultiFitUITable.Data(:, 2));
                app.regEndDay = table2array(app.MultiFitUITable.Data(:, 3));
                
            else
                app.regLineNo = (1 : value)';
                app.regStartDay = app.regStartDay(1 : value);
                app.regEndDay = app.regEndDay(1 : value);
                app.MultiFitUITable.Data = table(app.regLineNo, app.regStartDay, app.regEndDay, 'VariableNames',{'Lines','InitialDay', 'TerminatedDay'});
                
                
            end
            
        end

        % Cell edit callback: MultiFitUITable
        function MultiFitUITableCellEdit(app, event)
            %app.MultiFitUITable.Data(:, 2)
            app.regStartDay = table2array(app.MultiFitUITable.Data(:, 2));
            app.regEndDay = table2array(app.MultiFitUITable.Data(:, 3));
            app.regStartDay
            app.regEndDay
        end

        % Value changed function: ChiPCheckBox
        function ChiPCheckBoxValueChanged(app, event)
            value = app.ChiPCheckBox.Value;
            if value
                app.ActoData = ChiP(app.ActoData);
            else
                figure(2266)
                close
            end
        end

        % Close request function: FanaticActoUIFigure
        function FanaticActoUIFigureCloseRequest(app, event)
            delete(app.ActoFigureUI)
            delete(app)
            
        end

        % Value changed function: HoursDayEditField
        function HoursDayEditFieldValueChanged(app, event)
            app.ActoData.HRPERDAY = app.HoursDayEditField.Value;
                % Bins Per Hour
                %app.ActoData.BINPERHR = app.BinPerHour.Value;
                %app.ActoData.MINPERBIN = 60/app.ActoData.BINPERHR;
                %app.ActoData.BINHALFHR = app.ActoData.BINPERHR/2;
                app.ActoData.BINPERDAY = app.ActoData.BINPERHR * app.ActoData.HRPERDAY;
            
            if app.FileLoaded
                c = clock;
                hourPerTickValue = app.HourPerTickEditField.Value;
                app.ActoData = data2Hist(app.ActoData);
                app.ActoData = hist2Graph(app.ActoData);
                app.ActoData = fastTick(app.ActoData, hourPerTickValue);
                
                drawActo(app)
                c = clock - c;
                cc = c(5)*60 + c(6);
                app.PleaseLoadFileAgainLabel.Text = ['It takes ' num2str(round(cc, 2)) ' seconds'];
                app.PleaseLoadFileAgainLabel.Visible = 'on';
            
            end
        end

        % Value changed function: HighlightCheckBox
        function HighlightCheckBoxValueChanged(app, event)
            %value = app.HighlightCheckBox.Value;
            if app.FileLoadeddrawActo(app);
            end
            
        end

        % Button pushed function: ListButton
        function ListButtonPushed(app, event)
            
            %app.mynewfigure = uiAxis;
            %app.mynewfigure.Name = 'My New Figure';
            %plot(app.mynewfigure, 'parent', [0 1], [0 1])
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FanaticActoUIFigure = uifigure;
            app.FanaticActoUIFigure.Name = 'Fanatic Acto';
            app.FanaticActoUIFigure.CloseRequestFcn = createCallbackFcn(app, @FanaticActoUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.FanaticActoUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.LoadFileMenu = uimenu(app.FileMenu);
            app.LoadFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileMenu.Accelerator = 'f';
            app.LoadFileMenu.Text = 'Load File';
            app.SelectFileMenu = uimenu(app.FileMenu);
            app.SelectFileMenu.Text = 'Select File';
            app.Menu = uimenu(app.SelectFileMenu);
            app.Menu.Text = 'Menu';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Separator = 'on';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as ...';
            app.CircadianMenu = uimenu(app.FanaticActoUIFigure);
            app.CircadianMenu.Text = 'Circadian';
            app.LocalGMTMenu = uimenu(app.CircadianMenu);
            app.LocalGMTMenu.Text = 'Local GMT';
            app.GraphicMenu = uimenu(app.FanaticActoUIFigure);
            app.GraphicMenu.Text = 'Graphic';
            app.ImportMaskFileMenu = uimenu(app.GraphicMenu);
            app.ImportMaskFileMenu.Text = 'Import Mask File';
            app.AdjustMaskalphaMenu = uimenu(app.GraphicMenu);
            app.AdjustMaskalphaMenu.Text = 'Adjust Mask alpha';
            app.LDScalePositionMenu = uimenu(app.GraphicMenu);
            app.LDScalePositionMenu.Text = 'L/D Scale Position';
            app.TopMenu = uimenu(app.LDScalePositionMenu);
            app.TopMenu.Checked = 'on';
            app.TopMenu.Text = 'Top';
            app.BottomMenu = uimenu(app.LDScalePositionMenu);
            app.BottomMenu.Text = 'Bottom';
            app.ColorMenu = uimenu(app.FanaticActoUIFigure);
            app.ColorMenu.Text = 'Color';
            app.LoadFileButton = uibutton(app.FanaticActoUIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.UIAxes = uiaxes(app.FanaticActoUIFigure);
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.CircadianButton = uibutton(app.FanaticActoUIFigure, 'push');
            app.CircadianButton.ButtonPushedFcn = createCallbackFcn(app, @CircadianButtonPushed, true);
            app.CircadianButton.Text = 'Circadian';
            app.FanaticActoLabel = uilabel(app.FanaticActoUIFigure);
            app.FanaticActoLabel.Text = 'Fanatic Acto';
            app.LightHourLabel = uilabel(app.FanaticActoUIFigure);
            app.LightHourLabel.Text = 'Light Hour:';
            app.LightHourEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.LightHourEditField.Limits = [-48 48];
            app.LightHourEditField.ValueChangedFcn = createCallbackFcn(app, @LightHourEditFieldValueChanged, true);
            app.HoursDayEditFieldLabel = uilabel(app.FanaticActoUIFigure);
            app.HoursDayEditFieldLabel.Text = 'Hours/Day';
            app.HoursDayEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.HoursDayEditField.Limits = [0 48];
            app.HoursDayEditField.ValueChangedFcn = createCallbackFcn(app, @HoursDayEditFieldValueChanged, true);
            app.HoursDayEditField.Value = 24;
            app.EndDayLabel = uilabel(app.FanaticActoUIFigure);
            app.EndDayLabel.Text = 'End Day:';
            app.EndDayDropDown = uidropdown(app.FanaticActoUIFigure);
            app.EndDayDropDown.ValueChangedFcn = createCallbackFcn(app, @EndDayDropDownValueChanged, true);
            app.StartDayLabel = uilabel(app.FanaticActoUIFigure);
            app.StartDayLabel.Text = 'Start Day:';
            app.StartDayDropDown = uidropdown(app.FanaticActoUIFigure);
            app.StartDayDropDown.ValueChangedFcn = createCallbackFcn(app, @StartDayDropDownValueChanged, true);
            app.DoublePlotCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.DoublePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoublePlotCheckBoxValueChanged, true);
            app.DoublePlotCheckBox.Text = 'Double Plot';
            app.DoublePlotCheckBox.Value = true;
            app.LDMaskCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.LDMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @LDMaskCheckBoxValueChanged, true);
            app.LDMaskCheckBox.Text = 'L/D Mask';
            app.CageLabel = uilabel(app.FanaticActoUIFigure);
            app.CageLabel.Text = 'Cage:';
            app.CageDropDown = uidropdown(app.FanaticActoUIFigure);
            app.CageDropDown.ValueChangedFcn = createCallbackFcn(app, @CageDropDownValueChanged, true);
            app.GraphButton = uibutton(app.FanaticActoUIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.PleaseLoadFileAgainLabel = uilabel(app.FanaticActoUIFigure);
            app.PleaseLoadFileAgainLabel.Text = 'Please Load File Again.';
            app.SpaceDayLabel = uilabel(app.FanaticActoUIFigure);
            app.SpaceDayLabel.Text = 'Space/Day';
            app.SpaceDayEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.SpaceDayEditField.Limits = [0 Inf];
            app.SpaceDayEditField.RoundFractionalValues = 'on';
            app.SpaceDayEditField.ValueDisplayFormat = '%.0f';
            app.SpaceDayEditField.ValueChangedFcn = createCallbackFcn(app, @SpaceDayEditFieldValueChanged, true);
            app.SpaceDayEditField.Value = 3;
            app.pixLabel = uilabel(app.FanaticActoUIFigure);
            app.pixLabel.Text = 'pix';
            app.BinsHourDropDownLabel = uilabel(app.FanaticActoUIFigure);
            app.BinsHourDropDownLabel.Text = 'Bins / Hour';
            app.BinsHourDropDown = uidropdown(app.FanaticActoUIFigure);
            app.BinsHourDropDown.Items = {'12'};
            app.BinsHourDropDown.ItemsData = {'1'};
            app.BinsHourDropDown.ValueChangedFcn = createCallbackFcn(app, @BinsHourDropDownValueChanged, true);
            app.BinsHourDropDown.Value = '1';
            app.BinHeightEditFieldLabel = uilabel(app.FanaticActoUIFigure);
            app.BinHeightEditFieldLabel.Text = 'Bin Height';
            app.BinHeightEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.BinHeightEditField.Limits = [1 Inf];
            app.BinHeightEditField.RoundFractionalValues = 'on';
            app.BinHeightEditField.ValueDisplayFormat = '%.0f';
            app.BinHeightEditField.Value = 50;
            app.BinWidthEditFieldLabel = uilabel(app.FanaticActoUIFigure);
            app.BinWidthEditFieldLabel.Text = 'Bin Width';
            app.BinWidthEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.BinWidthEditField.Limits = [1 Inf];
            app.BinWidthEditField.RoundFractionalValues = 'on';
            app.BinWidthEditField.ValueDisplayFormat = '%.0f';
            app.BinWidthEditField.Value = 5;
            app.ColorSampleEditFieldLabel = uilabel(app.FanaticActoUIFigure);
            app.ColorSampleEditFieldLabel.Text = 'Color Sample';
            app.ColorSampleEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.ColorSampleEditField.ValueDisplayFormat = '%.0f';
            app.ColorSampleEditField.ValueChangedFcn = createCallbackFcn(app, @ColorSampleEditFieldValueChanged, true);
            app.ColorButton = uibutton(app.FanaticActoUIFigure, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.GradientEditFieldLabel = uilabel(app.FanaticActoUIFigure);
            app.GradientEditFieldLabel.Text = 'Gradient';
            app.GradientEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.GradientEditField.ValueDisplayFormat = '%.0f';
            app.UIAxes2 = uiaxes(app.FanaticActoUIFigure);
            title(app.UIAxes2, 'Bin Gradient')
            app.pixLabel_2 = uilabel(app.FanaticActoUIFigure);
            app.pixLabel_2.Text = 'pix';
            app.pixLabel_3 = uilabel(app.FanaticActoUIFigure);
            app.pixLabel_3.Text = 'pix';
            app.PredictionButton = uibutton(app.FanaticActoUIFigure, 'push');
            app.PredictionButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionButtonPushed, true);
            app.PredictionButton.Text = 'Prediction';
            app.ManualMaskCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.ManualMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualMaskCheckBoxValueChanged, true);
            app.ManualMaskCheckBox.Enable = 'off';
            app.ManualMaskCheckBox.Text = 'Manual Mask';
            app.LDScaleCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.LDScaleCheckBox.Text = 'L/D Scale';
            app.HourXTickLabel = uilabel(app.FanaticActoUIFigure);
            app.HourXTickLabel.Text = 'Hour/XTick';
            app.HourPerTickEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.HourPerTickEditField.ValueChangedFcn = createCallbackFcn(app, @HourPerTickEditFieldValueChanged, true);
            app.HourPerTickEditField.Value = 1;
            app.OnsetMarkCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.OnsetMarkCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnsetMarkCheckBoxValueChanged, true);
            app.OnsetMarkCheckBox.Text = 'Onset Mark';
            app.DayDropDownLabel = uilabel(app.FanaticActoUIFigure);
            app.DayDropDownLabel.Text = 'Day';
            app.DayDropDown = uidropdown(app.FanaticActoUIFigure);
            app.DayDropDown.ValueChangedFcn = createCallbackFcn(app, @DayDropDownValueChanged, true);
            app.StarNODropDownLabel = uilabel(app.FanaticActoUIFigure);
            app.StarNODropDownLabel.Text = 'Star NO.';
            app.StarNODropDown = uidropdown(app.FanaticActoUIFigure);
            app.StarNODropDown.Items = {'1', '2', '3', '4', '5', 'NaN'};
            app.StarNODropDown.ValueChangedFcn = createCallbackFcn(app, @StarNODropDownValueChanged, true);
            app.StarNODropDown.Value = '1';
            app.ListButton = uibutton(app.FanaticActoUIFigure, 'push');
            app.ListButton.ButtonPushedFcn = createCallbackFcn(app, @ListButtonPushed, true);
            app.ListButton.Text = 'List';
            app.RegressionCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.RegressionCheckBox.ValueChangedFcn = createCallbackFcn(app, @RegressionCheckBoxValueChanged, true);
            app.RegressionCheckBox.Text = 'Regression';
            app.PredDayEditFieldLabel = uilabel(app.FanaticActoUIFigure);
            app.PredDayEditFieldLabel.Text = 'Pred Day';
            app.PredDayEditField = uieditfield(app.FanaticActoUIFigure, 'numeric');
            app.PredDayEditField.ValueDisplayFormat = '%.0f';
            app.PredDayEditField.ValueChangedFcn = createCallbackFcn(app, @PredDayEditFieldValueChanged, true);
            app.MultiFitUITable = uitable(app.FanaticActoUIFigure);
            app.MultiFitUITable.ColumnName = {'No.'; 'Initial Day'; 'Terminated Day'};
            app.MultiFitUITable.RowName = {};
            app.MultiFitUITable.ColumnEditable = [false false true];
            app.MultiFitUITable.CellEditCallback = createCallbackFcn(app, @MultiFitUITableCellEdit, true);
            app.RegSpinner = uispinner(app.FanaticActoUIFigure);
            app.RegSpinner.Limits = [1 Inf];
            app.RegSpinner.ValueChangedFcn = createCallbackFcn(app, @RegSpinnerValueChanged, true);
            app.RegSpinner.Value = 1;
            app.ChiPCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.ChiPCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChiPCheckBoxValueChanged, true);
            app.ChiPCheckBox.Text = 'ChiP';
            app.Label = uilabel(app.FanaticActoUIFigure);
            app.Label.Text = '~';
            app.DayDropDown_2Label = uilabel(app.FanaticActoUIFigure);
            app.DayDropDown_2Label.Text = 'Day';
            app.DayDropDown_2 = uidropdown(app.FanaticActoUIFigure);
            app.DayDropDown_3Label = uilabel(app.FanaticActoUIFigure);
            app.DayDropDown_3Label.Text = 'Day';
            app.DayDropDown_3 = uidropdown(app.FanaticActoUIFigure);
            app.DisplayRangeLabel = uilabel(app.FanaticActoUIFigure);
            app.DisplayRangeLabel.Text = 'Display Range:';
            app.ShowRangeLabel = uilabel(app.FanaticActoUIFigure);
            app.ShowRangeLabel.Text = 'Show Range:';
            app.ProgressBarEditField = uieditfield(app.FanaticActoUIFigure, 'text');
            app.ProgressBarEditField.Editable = 'off';
            app.ProgressBarEditField.Visible = 'off';
            app.HighlightCheckBox = uicheckbox(app.FanaticActoUIFigure);
            app.HighlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighlightCheckBoxValueChanged, true);
            app.HighlightCheckBox.Text = 'Highlight';
        end
    end

    methods (Access = public)

        % Construct app
        function app = FanaticActo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FanaticActoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FanaticActoUIFigure)
        end
    end
end